classdef arduinoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InfoTextArea            matlab.ui.control.TextArea
        InfoTextArea_2          matlab.ui.control.TextArea
        STOPButton              matlab.ui.control.Button
        AARCOASISLabel          matlab.ui.control.Label
        DirectionKnobLabel      matlab.ui.control.Label
        DirectionKnob           matlab.ui.control.DiscreteKnob
        SpeedSlidermmminLabel   matlab.ui.control.Label
        SpeedSlidermmmin        matlab.ui.control.Slider
        CurrentModeButtonGroup  matlab.ui.container.ButtonGroup
        AutomaticButton         matlab.ui.control.RadioButton
        ManualButton            matlab.ui.control.RadioButton
        ManualCommandsLabel     matlab.ui.control.Label
        PositionKnobLabel       matlab.ui.control.Label
        PositionKnob            matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = public)
        ROP_Speed_Cmd
        ROP_Direction_Cmd
        Drilling_Mode
        Position_Cmd
    end
    
    methods (Access = private)
        
        function results = reset(app)
            app.SpeedSlidermmmin.Value = 0;
            app.ROP_Speed_Cmd = 0; %move speed multiplier
%             assignin('base','rop_speed_cmd', app.ROP_Speed_Cmd);
            
            app.DirectionKnob.Value = "Stop"; %stop mode
            app.ROP_Direction_Cmd = 0;
            app.PositionKnob = 0
            app.Position_Cmd = 0;
%             assignin('base','rop_direction_cmd', app.ROP_Direction_Cmd);
            
            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; %manual mode
%             assignin('base','drilling_mode', app.Drilling_Mode);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SpeedSlidermmmin.Value = 0;
            app.ROP_Speed_Cmd = 0; 
            app.PositionKnobLabel%move speed multiplier
%             assignin('base','rop_speed_cmd', app.ROP_Speed_Cmd);
            
            app.ROP_Direction_Cmd = 0;
            app.DirectionKnob.Value = "Stop"; %stop mode
%             assignin('base','rop_direction_cmd', app.ROP_Direction_Cmd);
            
            app.Drilling_Mode = 1; %manual mode
            app.ManualButton.Value = 1;
%             assignin('base','drilling_mode', app.Drilling_Mode);
         
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            value = app.DirectionKnob.Value; 
            if (value == "Up")
                app.ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.ROP_Direction_Cmd = 1;
            end
%             assignin('base','rop_direction_cmd', app.ROP_Direction_Cmd);
        end

        % Value changed function: SpeedSlidermmmin
        function SpeedSlidermmminValueChanged(app, event)
            value = app.SpeedSlidermmmin.Value;
            app.ROP_Speed_Cmd = value;
%             assignin('base','rop_speed_cmd', app.ROP_Speed_Cmd);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                reset(app);
                app.ROP_Speed_Cmd = 0;
%                 assignin('base','rop_speed_cmd', app.ROP_Speed_Cmd);
        end

        % Selection changed function: CurrentModeButtonGroup
        function CurrentModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.CurrentModeButtonGroup.SelectedObject;
            if (app.AutomaticButton.Value) 
                app.Drilling_Mode = 0; %automatic mode
            elseif (app.ManualButton.Value)
                app.Drilling_Mode = 1; %manual mode
            end
          
%             assignin('base','drilling_mode', app.Drilling_Mode);
        end

        % Value changing function: SpeedSlidermmmin
        function SpeedSlidermmminValueChanging(app, event)
            changingValue = event.Value;
            app.ROP_Speed_Cmd = str2int(changingValue);
%             assignin('base','rop_speed_cmd', app.ROP_Speed_Cmd);
        end

        % Value changed function: PositionKnob
        function PositionKnobValueChanged(app, event)
            value = app.PositionKnob.Value;
            app.Position_Cmd = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Value = {'Pressing Drilling Mode will start drilling, limiting WOB to 100N'};
            app.InfoTextArea_2 = uitextarea(app.UIFigure);
            app.InfoTextArea_2.Value = {'Pressing Manual Mode will allow user commands'};
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.AARCOASISLabel = uilabel(app.UIFigure);
            app.AARCOASISLabel.Text = 'AARC - OASIS';
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction Knob';
            app.DirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.DirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.Value = 'Stop';
            app.SpeedSlidermmminLabel = uilabel(app.UIFigure);
            app.SpeedSlidermmminLabel.Text = 'Speed Slider (mm/min)';
            app.SpeedSlidermmmin = uislider(app.UIFigure);
            app.SpeedSlidermmmin.Limits = [0 1000];
            app.SpeedSlidermmmin.ValueChangedFcn = createCallbackFcn(app, @SpeedSlidermmminValueChanged, true);
            app.SpeedSlidermmmin.ValueChangingFcn = createCallbackFcn(app, @SpeedSlidermmminValueChanging, true);
            app.CurrentModeButtonGroup = uibuttongroup(app.UIFigure);
            app.CurrentModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CurrentModeButtonGroupSelectionChanged, true);
            app.CurrentModeButtonGroup.Title = 'Current Mode';
            app.AutomaticButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ManualCommandsLabel = uilabel(app.UIFigure);
            app.ManualCommandsLabel.Text = 'Manual Commands';
            app.PositionKnobLabel = uilabel(app.UIFigure);
            app.PositionKnobLabel.Text = 'Position Knob';
            app.PositionKnob = uiknob(app.UIFigure, 'discrete');
            app.PositionKnob.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.PositionKnob.ValueChangedFcn = createCallbackFcn(app, @PositionKnobValueChanged, true);
            app.PositionKnob.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end